classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CTMRLSIMPanel                   matlab.ui.container.Panel
        XCATPhantomcharacteristicsPanel  matlab.ui.container.Panel
        SimulatedSliceThicknessEditField  matlab.ui.control.NumericEditField
        SimulatedSliceThicknessEditFieldLabel  matlab.ui.control.Label
        NumberofColumnsEditField        matlab.ui.control.NumericEditField
        NumberofColumnsEditFieldLabel   matlab.ui.control.Label
        NumberofRowsEditField           matlab.ui.control.NumericEditField
        NumberofRowsEditFieldLabel      matlab.ui.control.Label
        numberofslicesEditField         matlab.ui.control.NumericEditField
        numberofslicesEditFieldLabel    matlab.ui.control.Label
        InformationforthesimulatedCTscansand035TMRimagesPanel  matlab.ui.container.Panel
        SavePatientIDasTextArea         matlab.ui.control.TextArea
        SavePatientIDasTextAreaLabel    matlab.ui.control.Label
        SaveFamilyNameasTextArea        matlab.ui.control.TextArea
        SaveFamilyNameasTextAreaLabel   matlab.ui.control.Label
        SavePatientNameasTextArea       matlab.ui.control.TextArea
        SavePatientNameasTextAreaLabel  matlab.ui.control.Label
        Button_2                        matlab.ui.control.StateButton
        SaveCTMRImagesatTextArea        matlab.ui.control.TextArea
        SaveCTMRImagesatTextAreaLabel   matlab.ui.control.Label
        SyntheticImagesweregeneratedLamp  matlab.ui.control.Lamp
        SyntheticImagesweregeneratedLampLabel  matlab.ui.control.Label
        GenerateSytheticImagesButton    matlab.ui.control.StateButton
        XCATPhantomTextArea             matlab.ui.control.TextArea
        XCATPhantomLabel                matlab.ui.control.Label
        Button                          matlab.ui.control.StateButton
        ImportXCATPhantomanddefineOutputdestinationPanel  matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GenerateSytheticImagesButton
        function GenerateSytheticImagesButtonValueChanged(app, event)
     
            %%%%%%%%%%%%%%%%%%%%%%%% OPEN XCAT %%%%%%%%%%%%%%%%%%%%%%%%%%%%
         XCAT_file=convertCharsToStrings(app.XCATPhantomTextArea.Value);
         fxcat = fopen (XCAT_file, 'r','l');
         %Matrix_Slices = zeros(app.NumberofRowsEditField.Value,app.NumberofColumnsEditField.Value,app.numberofslicesEditField.Value);
         
         PatientName.FamilyName =convertCharsToStrings(app.SaveFamilyNameasTextArea.Value);
         PatientName.GivenName = convertCharsToStrings(app.SavePatientNameasTextArea.Value);
         Patient_ID = convertCharsToStrings(app.SavePatientIDasTextArea.Value);
         Study_ID = 'XCAT_CTMRLSIM';
         D=char(datetime('now', 'Format', 'yyyyMMdd.HHmm'));
         H=split(D,'.');
         DATE=char(H(1,1));
         TIME=char(H(2,1));
         IMGOrientPatient = [1;0;0;0;1;0];    %ImageOrientationPatient metadata information
         rng('default')
         format longG;
         C = zeros (app.NumberofRowsEditField.Value,app.NumberofColumnsEditField.Value);
         M = zeros (app.NumberofRowsEditField.Value,app.NumberofColumnsEditField.Value);
         InvC = zeros (app.NumberofColumnsEditField.Value,app.NumberofRowsEditField.Value);
         InvM = zeros (app.NumberofColumnsEditField.Value,app.NumberofRowsEditField.Value);

         %%%%%%%%%%%%%%%%%%% METADATA %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         SOP='1.2.840.10006.';
         SOPInstUIDNum='1.3.6.1.4.1.9590.100.1.';
         SeriesInst='1.2.826.0.1.3680043.8.498.';

         %%%%%%%%%%%%%%%%%%%%% SOPClassUID Generator &  %%%%%%%%%%%%%%%%%%%%%%%%
         J1=num2str(randi([1 5]));
         J2=num2str(randi([1 5]));
         J3=num2str(randi([1 5]));
         J4=num2str(randi([1 5]));
         T4=num2str(randi([1 5]));
            
         %%%%%%%%%%%%% Implementation ClassUID NUMBER GENERATOR %%%%%%%%%%%%%%%
         K1=num2str(randi([100 999]));       %% Uniformly distributed random integers
         ImplementationClassUID =[SOPInstUIDNum J3 '.' K1 '.' J4 '.' T4];
         %%%%%%%%%%%%%%%%%%% StudyInstanceUID GENERATOR %%%%%%%%%%%%%%%%%%%%%%%        
         t_1=num2str(randi([1000000 9999999]));    %% Uniformly distributed random integers
         t_2=num2str(randi([100 999]));
         t_3=num2str(randi([1000000000 9999999999]));
         t_4=num2str(randi([10000 99999]));
         t_5=num2str(randi([1000000000 9999999999]));
         StudyInstanceUID = [SOP t_1 '.' T4 '.' J1 '.' J2 '.' t_3 '.' t_4 '.' t_5 '.' t_2];
         %%%%%% SeriesInstanceUID & FrameOfReferenceUID NUMBER GENERATOR %%%%%%%%%%%%%%%%%%%%%%%%%%%%
         j1=num2str(randi([100000000000000 999999999999999])); %Uniformly distributed random integers IMIN and IMAX must be less than 15 digits
         j2=num2str(randi([100000000000000 999999999999999])); %Uniformly distributed random integers IMIN and IMAX must be less than 15 digits
         j3=num2str(randi([100000000000000 999999999999999]));
         j4=num2str(randi([100000000000000 999999999999999]));
         j5=num2str(randi([10000000 99999999]));
         j6=num2str(randi([10000000 99999999]));
         SeriesInstanceUID = [SeriesInst j1 j3 j5];
         FrameOfReferenceUID = [SeriesInst j2 j4 j6];

         %%%%%% SeriesInstanceUID & FrameOfReferenceUID NUMBER GENERATOR MR%%%%%%
         j7=num2str(randi([10000000 99999999]));
         SeriesInstanceUID_MR = [SeriesInst j1 j3 j7];
         FrameOfReferenceUID_MR = [SeriesInst j1 j4 j6];
         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


         for S = 1:app.numberofslicesEditField.Value
             data = fread(fxcat,[app.NumberofRowsEditField.Value,app.NumberofColumnsEditField.Value],'float32');% Read data from binary file. [512,512] m-by-n matrix, filled in column order
                
         %%%%%%%%%%%%%%%%%%% GENERATE CT & MRL %%%%%%%%%%%%%%%%%%%%%%%%%%%
            

            for r = 1:app.NumberofRowsEditField.Value
                for c = 1:app.NumberofColumnsEditField.Value
                    voxel_inf = data(r, c);
                    Lin = round(voxel_inf,8);
                    
                   if Lin ==  0.01679432
                       C(r,c)= random('Normal',56.40,16.27);
                       MM = random('Normal',64.65,15.66);
                       while MM < 0
                       MM = random('Normal',64.65,15.66);
                       end
                       M(r,c) = MM;
    
                   elseif Lin == 0.00479338
                       C(r,c)= random('Normal',-858.72,70.78);
                       MM = random('Normal',15.84,6.88);
                       while MM < 0
                       MM = random('Normal',15.84,6.88);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.02317102
                       C(r,c)= random('Normal',389.81,157.13);
                       MM = random('Normal',116.83,31.46);
                       while MM < 0
                       MM = random('Normal',116.83,31.46);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.03180122
                       C(r,c)= random('Normal',458.77,173.56);
                       MM = random('Normal',127.14,37.19);
                       while MM < 0
                       MM = random('Normal',127.14,37.19);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01480754
                       C(r,c)= random('Normal',-104.54,15.14);
                       MM = random('Normal',226.88,25.13);
                       while MM < 0
                       MM = random('Normal',226.88,25.13);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01696735
                       C(r,c)= random('Normal',36.34,11.60);
                       MM = random('Normal',83.44,29.15);
                       while MM < 0
                       MM = random('Normal',83.44,29.15);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01681971
                       C(r,c)= random('Normal',43.35,18.47);
                       MM = random('Normal',128.55,23.37);
                       while MM < 0
                       MM = random('Normal',128.55,23.37);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01680862
                       C(r,c)= random('Normal',30.61,12.52);
                       MM = random('Normal',117.02,14.80);
                       while MM < 0
                       MM = random('Normal',117.02,14.80);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01695572
                       C(r,c)= random('Normal',56.40,11.89);
                       MM = random('Normal',122.58,14.32);
                       while MM < 0
                       MM = random('Normal',122.58,14.32);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01656721
                       C(r,c)= random('Normal',-11.60,22.49);
                       MM = random('Normal',189.05,34.65);
                       while MM < 0
                       MM = random('Normal',189.05,34.65);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01667363
                       C(r,c)= random('Normal',28.96,12.96);
                       MM = random('Normal',128.63,17.50);
                       while MM < 0
                       MM = random('Normal',128.63,17.50);
                       end
                       M(r,c) = MM;

                    elseif Lin == 0.01652031
                       C(r,c)= random('Normal',28.40,24.42);
                       MM = random('Normal',84.63,29.81);
                       while MM < 0
                       MM = random('Normal',84.63,29.81);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01755936
                       C(r,c)= random('Normal',418.97,18.31);
                       MM = random('Normal',384.62,94.52);
                       while MM < 0
                       MM = random('Normal',384.62,94.52);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.0169735
                       C(r,c)= random('Normal',44.65,10.53);
                       MM = random('Normal',115.23,12.58);
                       while MM < 0
                       MM = random('Normal',115.23,12.58);
                       Neg=Neg+1;
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01838052
                       C(r,c)= random('Normal',37.53,10.13);
                       MM = random('Normal',108.24,10.27);
                       while MM < 0
                       MM = random('Normal',108.24,10.27);
                       end
                       M(r,c) = MM;

                    elseif Lin == 0.00001753
                       C(r,c)= random('Normal',-965.43,35.24);
                       MM = random('Normal',18.48,7.76);
                       while MM < 0
                       MM = random('Normal',18.48,7.76);
                       end
                       M(r,c) = MM;
                    
                    elseif Lin == 0.01743613
                       C(r,c)= random('Normal',45.08,22.80);
                       MM = random('Normal',159.04,51.41);
                       while MM < 0
                       MM = random('Normal',159.04,51.41);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01645093
                       C(r,c)= random('Normal',-47.40,19.80);
                       MM = random('Normal',99.45,28.92);
                       while MM < 0
                       MM = random('Normal',99.45,28.92);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01570668
                       C(r,c)= random('Normal',-47.40,19.08);
                       MM = random('Normal',99.45,28.92);
                       while MM < 0
                       MM = random('Normal',99.45,28.92);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.01965385
                       C(r,c)= random('Normal',646.80,251.19);
                       MM = random('Normal',90.20,40.33);
                       while MM < 0
                       MM = random('Normal',90.20,40.33);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.02396895
                       C(r,c)= random('Normal',646.80,251.19);
                       MM = random('Normal',90.20,40.33);
                       while MM < 0
                       MM = random('Normal',90.20,40.33);
                       end
                       M(r,c) = MM;
    
                    elseif Lin == 0.00000000
                       C(r,c) = -1000;
                       M(r,c) = 10;
    
                    elseif Lin ==  0.01613668
                        C(r,c)= 0;
                        M(r,c) = 50;
    
                    else
                        disp('The selected XCAT slices include areas outside of the abdominal area')
    
                    end
                end
            end
            for n = 1:app.NumberofRowsEditField.Value
                for m = 1:app.NumberofColumnsEditField.Value
                    InvC(m,n) = C(n,m);
                    InvM(m,n) = M(n,m);
                end
            end
            NewC = cast(InvC,'int16');
            NewM = cast(InvM,'int16');
            SNumber=app.SimulatedSliceThicknessEditField.Value*(S-1);
            IMGpositionPatient = [0;0;SNumber];


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%DICOM SOPInstanceUID & MediaStorageSOPInstanceUID GENERATOR%%
            T1=num2str(randi([100000000000000 999999999999999]));
            T2=num2str(randi([100000000000000 999999999999999]));
            T3=num2str(randi([100000000 999999999]));
            SOPInstUID_CT =[SOPInstUIDNum T4 '.' T1 T2 T3];
            %%%%% DICOM SOPInstanceUID & MediaStorageSOPInstanceUID GENERATOR MR%%%%
            I3=num2str(randi([100000000 999999999]));
            SOPInstUID_MR =[SOPInstUIDNum T4 '.' T1 T2 I3];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            metadata_CT = struct( ...
            "MediaStorageSOPInstanceUID", SOPInstUID_CT,...
            "ImplementationClassUID", ImplementationClassUID,...
            "ImplementationVersionName", 'MATLAB IPT 9.4',...
            "SourceApplicationEntityTitle", 'GDCM/ITK 5.3.0', ...
            "ImageType", 'ORIGINAL\PRIMARY\AXIAL',...
            "SOPInstanceUID",SOPInstUID_CT,...
            "StudyDate", DATE,...
            "SeriesDate", DATE,...
            "ContentDate", DATE,...
            "StudyTime", TIME,...
            "SeriesTime", TIME,...
            "ContentTime", TIME,...
            "Modality",'CT',...
            "Manufacturer", 'Unknown manufacturer',...
            "StudyDescription", 'No study description', ...
            "SeriesDescription", 'No series description',...
            "ManufacturerModelName", 'Unknown model',...
            "PatientName", PatientName, ...
            "PatientID", Patient_ID, ...
            "SliceThickness",app.SimulatedSliceThicknessEditField.Value,...
            "KVP", '120',...
            "PatientPosition", 'HFS',...
            "StudyInstanceUID",StudyInstanceUID, ...
            "SeriesInstanceUID",SeriesInstanceUID, ...
            "StudyID",Study_ID, ...
            "SeriesNumber", 1, ...
            "InstanceNumber", app.numberofslicesEditField.Value , ...
            "PixelSpacing", [1.0000;1.0000], ...
            "ImagePositionPatient", IMGpositionPatient, ...
            "ImageOrientationPatient", IMGOrientPatient, ...
            "FrameOfReferenceUID",FrameOfReferenceUID,...
            "SamplesPerPixel", 1,...
            "RescaleIntercept", 0, ...
            "RescaleSlope", 1, ...
            "RescaleType", 'HU'); %% Creates new DICOM file with the data (image) int16 and dicom info.
            
            metadata_MR = struct( ...
            "Format", 'DICOM', ...
            "Width", app.NumberofColumnsEditField.Value, ...
            "Height", app.NumberofRowsEditField.Value, ...
            "MediaStorageSOPInstanceUID", SOPInstUID_MR,... %SAME AS SOPInstanceUID
            "ImplementationClassUID", ImplementationClassUID,... %SAME AS CT
            "ImplementationVersionName", 'MATLAB IPT 9.4',...
            "SpecificCharacterSet", 'ISO_IR 100', ...
            "ImageType", 'ORIGINAL\PRIMARY\EMPTY\EMPTY',...
            "SOPInstanceUID",SOPInstUID_MR,... %SAME AS MediaStorageSOPInstanceUID
            "StudyDate", DATE,...
            "SeriesDate", DATE,...
            "StudyTime", TIME,...
            "SeriesTime", TIME,...
            "AcquisitionTime", TIME,...
            "Modality",'MR',...
            "Manufacturer", 'Unknown manufacturer',...
            "StudyDescription", 'No study description', ...
            "SeriesDescription", 'No series description',...
            "ManufacturerModelName", 'Unknown model',...
            "PatientName", PatientName, ... %SAME AS CT
            "PatientID", Patient_ID, ... %SAME AS CT
            "ScanningSequence", 'GR',...
            "SequenceVariant", 'NONE',...
            "SliceThickness",app.SimulatedSliceThicknessEditField.Value,...
            "RepetitionTime", [], ...
            "EchoTime", [], ...
            "EchoTrainLength", [], ...
            "SAR", 0.0013, ...
            "PatientPosition", 'HFS',...
            "StudyInstanceUID", StudyInstanceUID, ... %SAME AS CT
            "SeriesInstanceUID",SeriesInstanceUID_MR, ... %SAME AS SOPInstanceUID with exception to last number
            "StudyID",Study_ID, ... %SAME AS CT
            "SeriesNumber", 1, ...
            "InstanceNumber", S , ...
            "PixelSpacing", [1.0000;1.0000], ...
            "ImagePositionPatient", IMGpositionPatient, ...
            "ImageOrientationPatient", IMGOrientPatient, ...
            "FrameOfReferenceUID",FrameOfReferenceUID_MR,... %SAME AS SeriesInstanceUID with exception to last number of two last sequences
            "SamplesPerPixel", 1,...
            "Rows", app.NumberofRowsEditField.Value, ...
            "Columns", app.NumberofColumnsEditField.Value);

            dicomwrite(NewC, sprintf('CTSIM_%04.4g.dcm',S), metadata_CT);
            dicomwrite(NewM, sprintf('MRLSIM_%04.4g.dcm',S), metadata_MR);

         end
        
        
        %app.SyntheticImagesweregeneratedLamp.HandleVisibility = On;
        end

        % Value changed function: numberofslicesEditField
        function numberofslicesEditFieldValueChanged(app, event)

        end

        % Value changed function: NumberofRowsEditField
        function NumberofRowsEditFieldValueChanged(app, event)

            
        end

        % Value changed function: NumberofColumnsEditField
        function NumberofColumnsEditFieldValueChanged(app, event)

            
        end

        % Value changed function: Button, SimulatedSliceThicknessEditField
        function ButtonValueChanged(app, event)
            [file, ~] = uigetfile({'*.*', 'All Files (*.*)'},'Select an XCAT phantom file');
                if isequal(file, 0)
                    app.FilePathArea.Value = {'No file selected.'};
                else
                    app.XCATPhantomTextArea.Value={file};
                end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CTMRLSIMPanel = uipanel(app.UIFigure);
            app.CTMRLSIMPanel.Title = 'CTMRLSIM';
            app.ImportXCATPhantomanddefineOutputdestinationPanel = uipanel(app.CTMRLSIMPanel);
            app.ImportXCATPhantomanddefineOutputdestinationPanel.Title = '1. Import XCAT Phantom and define Output destination';
            app.Button = uibutton(app.CTMRLSIMPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '...';
            app.XCATPhantomLabel = uilabel(app.CTMRLSIMPanel);
            app.XCATPhantomLabel.Text = 'XCAT Phantom:';
            app.XCATPhantomTextArea = uitextarea(app.CTMRLSIMPanel);
            app.GenerateSytheticImagesButton = uibutton(app.CTMRLSIMPanel, 'state');
            app.GenerateSytheticImagesButton.ValueChangedFcn = createCallbackFcn(app, @GenerateSytheticImagesButtonValueChanged, true);
            app.GenerateSytheticImagesButton.Text = 'Generate Sythetic Images';
            app.SyntheticImagesweregeneratedLampLabel = uilabel(app.CTMRLSIMPanel);
            app.SyntheticImagesweregeneratedLampLabel.Text = 'Synthetic Images were generated';
            app.SyntheticImagesweregeneratedLamp = uilamp(app.CTMRLSIMPanel);
            app.SaveCTMRImagesatTextAreaLabel = uilabel(app.CTMRLSIMPanel);
            app.SaveCTMRImagesatTextAreaLabel.Text = 'Save CT & MR Images at:';
            app.SaveCTMRImagesatTextArea = uitextarea(app.CTMRLSIMPanel);
            app.Button_2 = uibutton(app.CTMRLSIMPanel, 'state');
            app.Button_2.Text = '...';
            app.InformationforthesimulatedCTscansand035TMRimagesPanel = uipanel(app.CTMRLSIMPanel);
            app.InformationforthesimulatedCTscansand035TMRimagesPanel.Title = '3. Information for the simulated CT scans and 0.35T MR images';
            app.SavePatientNameasTextAreaLabel = uilabel(app.InformationforthesimulatedCTscansand035TMRimagesPanel);
            app.SavePatientNameasTextAreaLabel.Text = 'Save Patient Name as:';
            app.SavePatientNameasTextArea = uitextarea(app.InformationforthesimulatedCTscansand035TMRimagesPanel);
            app.SaveFamilyNameasTextAreaLabel = uilabel(app.InformationforthesimulatedCTscansand035TMRimagesPanel);
            app.SaveFamilyNameasTextAreaLabel.Text = 'Save Family Name as:';
            app.SaveFamilyNameasTextArea = uitextarea(app.InformationforthesimulatedCTscansand035TMRimagesPanel);
            app.SavePatientIDasTextAreaLabel = uilabel(app.InformationforthesimulatedCTscansand035TMRimagesPanel);
            app.SavePatientIDasTextAreaLabel.Text = 'Save Patient ID as:';
            app.SavePatientIDasTextArea = uitextarea(app.InformationforthesimulatedCTscansand035TMRimagesPanel);
            app.XCATPhantomcharacteristicsPanel = uipanel(app.CTMRLSIMPanel);
            app.XCATPhantomcharacteristicsPanel.Title = '2. XCAT Phantom characteristics';
            app.numberofslicesEditFieldLabel = uilabel(app.XCATPhantomcharacteristicsPanel);
            app.numberofslicesEditFieldLabel.Text = 'number of slices';
            app.numberofslicesEditField = uieditfield(app.XCATPhantomcharacteristicsPanel, 'numeric');
            app.numberofslicesEditField.Limits = [1 400];
            app.numberofslicesEditField.ValueChangedFcn = createCallbackFcn(app, @numberofslicesEditFieldValueChanged, true);
            app.numberofslicesEditField.Value = 76;
            app.NumberofRowsEditFieldLabel = uilabel(app.XCATPhantomcharacteristicsPanel);
            app.NumberofRowsEditFieldLabel.Text = 'Number of Rows';
            app.NumberofRowsEditField = uieditfield(app.XCATPhantomcharacteristicsPanel, 'numeric');
            app.NumberofRowsEditField.Limits = [10 500];
            app.NumberofRowsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofRowsEditFieldValueChanged, true);
            app.NumberofRowsEditField.Value = 400;
            app.NumberofColumnsEditFieldLabel = uilabel(app.XCATPhantomcharacteristicsPanel);
            app.NumberofColumnsEditFieldLabel.Text = 'Number of Columns';
            app.NumberofColumnsEditField = uieditfield(app.XCATPhantomcharacteristicsPanel, 'numeric');
            app.NumberofColumnsEditField.Limits = [10 500];
            app.NumberofColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofColumnsEditFieldValueChanged, true);
            app.NumberofColumnsEditField.Value = 400;
            app.SimulatedSliceThicknessEditFieldLabel = uilabel(app.XCATPhantomcharacteristicsPanel);
            app.SimulatedSliceThicknessEditFieldLabel.Text = 'Simulated Slice Thickness';
            app.SimulatedSliceThicknessEditField = uieditfield(app.XCATPhantomcharacteristicsPanel, 'numeric');
            app.SimulatedSliceThicknessEditField.Limits = [0.1 5];
            app.SimulatedSliceThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.SimulatedSliceThicknessEditField.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end